classdef APPV2V < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ParmetrosInicialesPanel         matlab.ui.container.Panel
        FrecuenciadePortadoraMin450MHzMax100GHzLabel  matlab.ui.control.Label
        fPortadora                      matlab.ui.control.NumericEditField
        retraso                         matlab.ui.control.CheckBox
        AnchodeBandaEditFieldLabel      matlab.ui.control.Label
        BW                              matlab.ui.control.NumericEditField
        NmerodeCarriersEditFieldLabel   matlab.ui.control.Label
        N                               matlab.ui.control.NumericEditField
        FrecuenciadeMuestreoEditFieldLabel  matlab.ui.control.Label
        fs                              matlab.ui.control.NumericEditField
        Coche1Panel                     matlab.ui.container.Panel
        VelocidadkmhEditField_2Label    matlab.ui.control.Label
        Velocidad1                      matlab.ui.control.NumericEditField
        AlturadelaAntenamEditField_2Label  matlab.ui.control.Label
        alturacoche1                    matlab.ui.control.NumericEditField
        TipodeAntenaDropDown1Label      matlab.ui.control.Label
        Antena1                         matlab.ui.control.DropDown
        Coche2Panel                     matlab.ui.container.Panel
        VelocidadkmhEditFieldLabel      matlab.ui.control.Label
        velocidad2                      matlab.ui.control.NumericEditField
        AlturadelaAntenamEditFieldLabel  matlab.ui.control.Label
        altura2                         matlab.ui.control.NumericEditField
        TipodeAntenaDropDown_2Label     matlab.ui.control.Label
        antena2                         matlab.ui.control.DropDown
        DistanciaentrecochesmLabel      matlab.ui.control.Label
        distancia                       matlab.ui.control.NumericEditField
        EjecutarQUADRIGAButton          matlab.ui.control.Button
        APPPARAEJECUCINDEQUADRIGALabel  matlab.ui.control.Label
        DistanciaentrecarrilesmLabel    matlab.ui.control.Label
        distancia_carriles              matlab.ui.control.NumericEditField
        FrecuenciaDopplerMximaEditFieldLabel  matlab.ui.control.Label
        fDop                            matlab.ui.control.NumericEditField
        DirecciondelCoche2DropDownLabel  matlab.ui.control.Label
        direccion                       matlab.ui.control.DropDown
        Layout                          matlab.ui.control.Button
        ENVIOATRAVESDELTRAYECTOLabel    matlab.ui.control.Label
        NmerodeBloquesdeRecursoEditFieldLabel  matlab.ui.control.Label
        numRB                           matlab.ui.control.NumericEditField
        TamaodelosBloquesdeRecursoEditFieldLabel  matlab.ui.control.Label
        rbSize                          matlab.ui.control.NumericEditField
        TamaodelPrefijoCclicoEditFieldLabel  matlab.ui.control.Label
        cpLen                           matlab.ui.control.NumericEditField
        PeriododeTramaEditFieldLabel    matlab.ui.control.Label
        frameDur                        matlab.ui.control.NumericEditField
        OFDM                            matlab.ui.control.Button
        FOFDM                           matlab.ui.control.Button
        UFMC                            matlab.ui.control.Button
        BERmedioEditFieldLabel          matlab.ui.control.Label
        BERmedioOFDM                    matlab.ui.control.NumericEditField
        THRmedioEditFieldLabel          matlab.ui.control.Label
        THRmedioOFDM                    matlab.ui.control.NumericEditField
        BERmedioEditFieldLabel_2        matlab.ui.control.Label
        BERmedioFOFDM                   matlab.ui.control.NumericEditField
        THRmedioEditFieldLabel_2        matlab.ui.control.Label
        THRmedioFOFDM                   matlab.ui.control.NumericEditField
        THRmedioUFMC                    matlab.ui.control.NumericEditField
        BERmedioEditFieldLabel_3        matlab.ui.control.Label
        BERmedioUFMC                    matlab.ui.control.NumericEditField
        THRmedioEditFieldLabel_3        matlab.ui.control.Label
        TiempodesimulacionEditFieldLabel  matlab.ui.control.Label
        tiempo                          matlab.ui.control.NumericEditField
        Retrasos                        matlab.ui.control.UIAxes
        ganancias                       matlab.ui.control.UIAxes
        doppler                         matlab.ui.control.UIAxes
        BERPlot                         matlab.ui.control.UIAxes
        THRPlot                         matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        v1
        v2
        PathDelay % Description
        PathGain
        l
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: EjecutarQUADRIGAButton
        function EjecutarQUADRIGAButtonPushed(app, event)

            %% GENERACION DEL CANAL.  
            update_rate = 10e-3;
            c = app.l.get_channels(update_rate);
            
            %% PATH DELAYS
            %Extraemos del canal generado los Path delays de las
            %conexiones entre Coche1-Coche2.
            
            cn=c(1,1).quantize_delays(1/app.fs.Value);
            cn.individual_delays = 0;
            PathDelays=cn.delay;
            filaprimercero=0;            
            for fila=1:numel(PathDelays(:,1)) 
                for columna=1:numel(PathDelays(1,:))
                    if(PathDelays(fila,columna)==0 && filaprimercero==0)
                        filaprimercero=fila;
                    end
                end
            end
            app.PathDelay=PathDelays(1:filaprimercero-1,:);
            PathMean=mean(PathDelays(1:filaprimercero-1,:));
            
            snaps = ( 0 : c(1,1).no_snap-1 ) ;                      % Número de "snapshot" del trayecto 
            plot(app.Retrasos, snaps , PathMean)
            axis(app.Retrasos,[0, max(snaps), min(PathMean(:)), max(PathMean(:))])
            xlabel(app.Retrasos,'Número de "snapshot" del trayecto')
            ylabel(app.Retrasos,'Retraso en [s]')
            title(app.Retrasos,'Retraso medio')
            
            save('PathDelays.mat','PathDelays');
            %% PATH GAINS 
            %Extraemos del canal generado los Path gains de las
            %conexiones entre Coche1-Coche2.
            
            snaps = ( 0 : c(1,1).no_snap-1 );          % Número de "snapshot" del trayecto
            PathGains = abs(cn.coeff).^2;
            PathGains=squeeze(PathGains(1,1,:,:));
            PathGains=10*log10(PathGains);
            PathGains=PathGains(1:filaprimercero-1,:);
            app.PathGain=PathGains;
            MeanPathGains=mean(PathGains(1:filaprimercero-1,:));
            
            plot(app.ganancias ,snaps , MeanPathGains )
            axis(app.ganancias ,[0, max(snaps), min(MeanPathGains(:))-5, max(MeanPathGains(:))+5])
            xlabel(app.ganancias ,'Número de "snapshot" del trayecto')
            ylabel(app.ganancias ,'Ganancia de trayecto en [dB]')
            title(app.ganancias ,'Ganancia media')
            
            save('PathGains.mat','PathGains');
            %% Doppler Spectrum Measurement
            w = 10;                                                 % Doppler analysis windows size (10 ms)
            
            if(strcmp(app.direccion.Value,'Misma Direccion'))
                vrel=max(app.v1,app.v2)-min(app.v1,app.v2);
            elseif(strcmp(app.direccion.Value,'Direccion Opuesta'))
                vrel=app.v1+app.v2;
            end
            
            app.fDop.Value=(2*vrel*app.fPortadora.Value/3e8);
            Doppler_axis = -app.fDop.Value:w:app.fDop.Value;           % The Doppler axis in Hz
            Time_axis = snaps( 1:w:end );                           % Time axis in seconds
            
            no_Doppler = floor( numel(snaps) ./ w );                % Number of Doppler samples
            
            Doppler_spectrum = zeros( w, no_Doppler );          % Preallocate Memory
            
            for n = 1 : floor( numel(snaps) ./ w )
                ind = (n-1)*w + 1 : n*w;                        % Snapshot indices
                H = c(1,1).fr( app.BW.Value, app.N.Value, ind );                    % Frequency response of the channel
                H = permute( H,[3,4,1,2] );                     % Reorder dimensions
                G = ifft2(H);                                   % 2D IFFT
                G = fftshift( G,2);                             % Center Doppler spectrum
                Doppler_spectrum( :,n ) = 10*log10( sum( abs(G).^2 , 1 )' );    % Logrithmic power
            end
            
            imagesc(app.doppler,Time_axis,Doppler_axis,Doppler_spectrum);   % Create images    
            colorbar
            title(app.doppler,'Espectro Doppler');
            axis(app.doppler,[0 max(Time_axis) -app.fDop.Value app.fDop.Value]);
            xlabel(app.doppler,'Time [s]'); ylabel(app.doppler,'Doppler shift [Hz]');
            colormap jet
                                   
        end

        % Button pushed function: Layout
        function LayoutButtonPushed(app, event)
            s = qd_simulation_parameters;                           
            s.center_frequency = app.fPortadora.Value;                              
            s.use_absolute_delays = app.retraso.Value;                              % Incluye el delay en las componentes LOS
            s.sample_density = 2.5;                                                 % Sample density minima.
            app.v1=app.Velocidad1.Value/3.6;
            app.v2=app.velocidad2.Value/3.6;
            
            %% COCHE1
            app.l = qd_layout( s );            
            d1=app.tiempo.Value*app.v1;
            t = qd_track( 'linear', d1 , 0);                       % Linea recta del coche 1 de X metros.
            t.set_speed( app.v1 );                                % Velocidad = 50 km/h
            t.interpolate('time',10e-3,[],[],1); % Interpolar a 10 ms cada snapshot.
            t.initial_position = [6;0;app.alturacoche1.Value];                      % Posicion Inicial
            t.name = 'Coche1';                                        

            a = qd_arrayant(app.Antena1.Value);                                    
            
            % Asignamos el track creado al escenario que vamos a simular.
            app.l.rx_track(1,1) = t.copy;                               
            app.l.rx_track(1,1).set_scenario([],[],[]);                 
            app.l.rx_array(1,1) = a;                                    
            
            app.l.tx_track(1,2) = t.copy;                               
            app.l.tx_array(1,2) = a;                                    
            
            %%COCHE2
            if(strcmp(app.direccion.Value,'Misma Direccion'))
                dir=0;
            elseif(strcmp(app.direccion.Value,'Direccion Opuesta'))
                dir=pi;
            end
            
            d2=app.tiempo.Value*app.v2;
            t = qd_track( 'linear', d2 , dir );                                               
            t.set_speed( app.v2 );                                                          
            t.interpolate('time',10e-3,[],[],1);                
            t.initial_position = [app.distancia.Value;app.distancia_carriles.Value;app.altura2.Value];         
            t.name = 'Coche2';                                                                                
            
            a = qd_arrayant(app.antena2.Value); 
            
            % Asignamos el track creado al escenario que vamos a simular.
            app.l.rx_track(1,2) = t;                            
            % Assign Rx track 2
            app.l.rx_track(1,2).set_scenario([],[],[]);                 % Create segments (rx-track only)
            app.l.rx_array(1,2) = a;                                    % Assign Rx array 2
            
            app.l.set_scenario('QuaDRiGa_UD2D');                        % Mobile tranceivers
            app.l.visualize([],[],0,0);                                 % Show BS and MT positions on the map
            
        end

        % Button pushed function: OFDM
        function OFDMButtonPushed(app, event)
           numDataCarriers = app.numRB.Value*app.rbSize.Value;                % Número de subcarriers con datos
           nulls = ((app.N.Value-numDataCarriers)/2);                         % Número de subcarriers nulas
           nullIdx = [1:nulls app.N.Value-nulls+1:app.N.Value].';             % Indices de las subcarriers nulas o de guardia
           PointMax=numel(app.PathGain(1,:));
           
           berVal = zeros(1,PointMax);
           THR = zeros(1,PointMax);
           MCS = zeros(1,PointMax);
           MCS(1) = 1;
           
           for i=1:PointMax
               %%TRANSMISOR
                [dataTX,signalTX]=MCSMod(app.numRB.Value,app.rbSize.Value,MCS(i));
                txSig = modOFDM(signalTX,app.N.Value,app.cpLen.Value,nullIdx);
                chan = comm.RayleighChannel('PathGainsOutputPort',true, ...
                'MaximumDopplerShift',app.fDop.Value,'PathDelays',app.PathDelay(:,i)', ...
                'AveragePathGains',app.PathGain(:,i)','SampleRate',app.fs.Value, ...
                'RandomStream','mt19937ar with seed');
                [chanSig,pg] = chan(txSig);
               %% ESTIMACION DE CANAL
                sampIdx = round(app.PathDelay(:,i)/(1/app.fs.Value)) + 1;
                hImp = complex(zeros(1,app.N.Value));
                hImp(:,sampIdx) = mean(pg,1);
                hEst = fftshift(fft(hImp.'),1);
                dataIdx = double(setdiff((1:app.N.Value)',nullIdx));
                h = hEst(dataIdx);
                eqH = conj(h)./(conj(h).*h);
                symOffset = min(max(sampIdx),app.cpLen.Value);
               %%RECEPTOR
                rxSig = demodOFDM(chanSig,app.N.Value,app.cpLen.Value,nullIdx,symOffset,eqH);
                dataRX = MCSDemod(rxSig,MCS(i));
                BER = comm.ErrorRate;
                ber = BER(dataTX, dataRX);
                berVal(i) = ber(1);
                numBitsCorrect = ber(3)-ber(2);
                THR(i)=numBitsCorrect/app.frameDur.Value;
                disp(['Track point number ' num2str(i) ' MCS-OFDM=' num2str(MCS(i)) ' BER-OFDM=' num2str(berVal(i))]);
                if(berVal(i)<=1e-5 && MCS(i)<8)
                    MCS(i+1)=MCS(i)+1;
                elseif(berVal(i)>1e-3 && MCS(i)>1)
                    MCS(i+1)=MCS(i)-1;
                else
                    MCS(i+1)=MCS(i);
                end
           end
           app.BERmedioOFDM.Value=mean(berVal);
           app.THRmedioOFDM.Value=mean(THR);
           
           track=1:PointMax;
           plot(app.BERPlot,track,berVal);
           axis(app.BERPlot,[0 PointMax 0 0.5])
           xlabel(app.BERPlot,'Trayecto')
           ylabel(app.BERPlot,'BER')
           title(app.BERPlot,"OFDM Bit Error Rate a lo largo del trayecto")
           
           plot(app.THRPlot,track,THR);
           axis(app.THRPlot,[0 PointMax 0 max(THR(:))+100000])
           xlabel(app.THRPlot,'TRACK')
           ylabel(app.THRPlot,'THROUGHPUT')
           title(app.THRPlot,"OFDM Throughput a lo largo del trayecto")
        end

        % Button pushed function: FOFDM
        function FOFDMButtonPushed(app, event)
           numDataCarriers = app.numRB.Value*app.rbSize.Value;                % Número de subcarriers con datos
           nulls = ((app.N.Value-numDataCarriers)/2);                         % Número de subcarriers nulas
           nullIdx = [1:nulls app.N.Value-nulls+1:app.N.Value].';             % Indices de las subcarriers nulas o de guardia
           PointMax=numel(app.PathGain(1,:));
           
           berVal = zeros(1,PointMax);
           THR = zeros(1,PointMax);
           MCS = zeros(1,PointMax);
           MCS(1) = 1;
           
           for i=1:PointMax
               %%TRANSMISOR
                [dataTX,signalTX]=MCSMod(app.numRB.Value,app.rbSize.Value,MCS(i));
                txSig = modFOFDM(signalTX,app.N.Value,app.cpLen.Value,nullIdx);
                chan = comm.RayleighChannel('PathGainsOutputPort',true, ...
                'MaximumDopplerShift',app.fDop.Value,'PathDelays',app.PathDelay(:,i)', ...
                'AveragePathGains',app.PathGain(:,i)','SampleRate',app.fs.Value, ...
                'RandomStream','mt19937ar with seed');
                [chanSig,pg] = chan(txSig);
               %% ESTIMACION DE CANAL
                sampIdx = round(app.PathDelay(:,i)/(1/app.fs.Value)) + 1;
                hImp = complex(zeros(1,app.N.Value));
                hImp(:,sampIdx) = mean(pg,1);
                hEst = fftshift(fft(hImp.'),1);
                dataIdx = double(setdiff((1:app.N.Value)',nullIdx));
                h = hEst(dataIdx);
                eqH = conj(h)./(conj(h).*h);
                symOffset = min(max(sampIdx),app.cpLen.Value);
               %%RECEPTOR
                rxSig = demodFOFDM(chanSig,app.N.Value,app.cpLen.Value,nullIdx,symOffset,eqH,numDataCarriers);
                dataRX = MCSDemod(rxSig,MCS(i));
                BER = comm.ErrorRate;
                ber = BER(dataTX, dataRX);
                berVal(i) = ber(1);
                numBitsCorrect = ber(3)-ber(2);
                THR(i)=numBitsCorrect/app.frameDur.Value;
                disp(['Track point number ' num2str(i) ' MCS-FOFDM=' num2str(MCS(i)) ' BER-FOFDM=' num2str(berVal(i))]);
                if(berVal(i)<=1e-5 && MCS(i)<8)
                    MCS(i+1)=MCS(i)+1;
                elseif(berVal(i)>1e-3 && MCS(i)>1)
                    MCS(i+1)=MCS(i)-1;
                else
                    MCS(i+1)=MCS(i);
                end
           end
           app.BERmedioFOFDM.Value=mean(berVal);
           app.THRmedioFOFDM.Value=mean(THR);
           
           track=1:PointMax;
           plot(app.BERPlot,track,berVal);
           axis(app.BERPlot,[0 PointMax 0 0.5])
           xlabel(app.BERPlot,'Trayecto')
           ylabel(app.BERPlot,'BER')
           title(app.BERPlot,"F-OFDM Bit Error Rate a lo largo del trayecto")
           
           plot(app.THRPlot,track,THR);
           axis(app.THRPlot,[0 PointMax 0 max(THR(:))+100000])
           xlabel(app.THRPlot,'TRACK')
           ylabel(app.THRPlot,'THROUGHPUT')
           title(app.THRPlot,"F-OFDM Throughput a lo largo del trayecto")            
        end

        % Button pushed function: UFMC
        function UFMCButtonPushed(app, event)

           PointMax=numel(app.PathGain(1,:));
           berVal = zeros(1,PointMax);
           THR = zeros(1,PointMax);
           MCS = zeros(1,PointMax);
           MCS(1) = 1;
           
           for i=1:PointMax
               %%TRANSMISOR
                [dataTX,signalTX]=MCSMod(app.numRB.Value,app.rbSize.Value,MCS(i));
                txSig = modUFMC(signalTX,app.N.Value,app.rbSize.Value,app.numRB.Value,app.cpLen.Value);
                chan = comm.RayleighChannel('PathGainsOutputPort',true, ...
                'MaximumDopplerShift',app.fDop.Value,'PathDelays',app.PathDelay(:,i)', ...
                'AveragePathGains',app.PathGain(:,i)','SampleRate',app.fs.Value, ...
                'RandomStream','mt19937ar with seed');
                [chanSig,pg] = chan(txSig);
               %% ESTIMACION DE CANAL
                sampIdx = round(app.PathDelay(:,i)/(1/app.fs.Value)) + 1;
                hImp = complex(zeros(1,app.N.Value));
                hImp(:,sampIdx) = mean(pg,1);
                hEst = fftshift(fft(hImp.'),1);
               %%RECEPTOR
                rxSig = demodUFMC(chanSig,app.N.Value,app.rbSize.Value,app.numRB.Value,app.cpLen.Value,hEst);
                dataRX = MCSDemod(rxSig,MCS(i));
                BER = comm.ErrorRate;
                ber = BER(dataTX, dataRX);
                berVal(i) = ber(1);
                numBitsCorrect = ber(3)-ber(2);
                THR(i)=numBitsCorrect/app.frameDur.Value;
                disp(['Track point number ' num2str(i) ' MCS-UFMC=' num2str(MCS(i)) ' BER-UFMC=' num2str(berVal(i))]);
                if(berVal(i)<=1e-5 && MCS(i)<8)
                    MCS(i+1)=MCS(i)+1;
                elseif(berVal(i)>1e-3 && MCS(i)>1)
                    MCS(i+1)=MCS(i)-1;
                else
                    MCS(i+1)=MCS(i);
                end
           end
           app.BERmedioUFMC.Value=mean(berVal);
           app.THRmedioUFMC.Value=mean(THR);
           
           track=1:PointMax;
           plot(app.BERPlot,track,berVal);
           axis(app.BERPlot,[0 PointMax 0 0.5])
           xlabel(app.BERPlot,'Trayecto')
           ylabel(app.BERPlot,'BER')
           title(app.BERPlot,"UFMC Bit Error Rate a lo largo del trayecto")
           
           plot(app.THRPlot,track,THR);
           axis(app.THRPlot,[0 PointMax 0 max(THR(:))+100000])
           xlabel(app.THRPlot,'TRACK')
           ylabel(app.THRPlot,'THROUGHPUT')
           title(app.THRPlot,"UFMC Throughput a lo largo del trayecto") 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ParmetrosInicialesPanel = uipanel(app.UIFigure);
            app.ParmetrosInicialesPanel.Title = 'Parámetros Iniciales';
            app.FrecuenciadePortadoraMin450MHzMax100GHzLabel = uilabel(app.ParmetrosInicialesPanel);
            app.FrecuenciadePortadoraMin450MHzMax100GHzLabel.Text = 'Frecuencia de Portadora(Min450MHz-Max100GHz)';
            app.fPortadora = uieditfield(app.ParmetrosInicialesPanel, 'numeric');
            app.retraso = uicheckbox(app.ParmetrosInicialesPanel);
            app.retraso.Text = 'Medir Retraso de Componentes LOS';
            app.AnchodeBandaEditFieldLabel = uilabel(app.ParmetrosInicialesPanel);
            app.AnchodeBandaEditFieldLabel.Text = 'Ancho de Banda';
            app.BW = uieditfield(app.ParmetrosInicialesPanel, 'numeric');
            app.NmerodeCarriersEditFieldLabel = uilabel(app.ParmetrosInicialesPanel);
            app.NmerodeCarriersEditFieldLabel.Text = 'Número de Carriers';
            app.N = uieditfield(app.ParmetrosInicialesPanel, 'numeric');
            app.FrecuenciadeMuestreoEditFieldLabel = uilabel(app.ParmetrosInicialesPanel);
            app.FrecuenciadeMuestreoEditFieldLabel.Text = 'Frecuencia de Muestreo';
            app.fs = uieditfield(app.ParmetrosInicialesPanel, 'numeric');
            app.Coche1Panel = uipanel(app.UIFigure);
            app.Coche1Panel.Title = 'Coche 1';
            app.VelocidadkmhEditField_2Label = uilabel(app.Coche1Panel);
            app.VelocidadkmhEditField_2Label.Text = 'Velocidad(km/h)';
            app.Velocidad1 = uieditfield(app.Coche1Panel, 'numeric');
            app.AlturadelaAntenamEditField_2Label = uilabel(app.Coche1Panel);
            app.AlturadelaAntenamEditField_2Label.Text = 'Altura de la Antena(m)';
            app.alturacoche1 = uieditfield(app.Coche1Panel, 'numeric');
            app.TipodeAntenaDropDown1Label = uilabel(app.Coche1Panel);
            app.TipodeAntenaDropDown1Label.Text = 'Tipo de Antena';
            app.Antena1 = uidropdown(app.Coche1Panel);
            app.Antena1.Items = {'omni', 'dipole', 'half-wave-dipole', 'patch', 'xpol', 'rhcp-dipole', 'lhcp-dipole', 'lhcp-rhcp-dipole', 'ula2', 'ula4', 'ula8'};
            app.Antena1.Value = 'omni';
            app.Coche2Panel = uipanel(app.UIFigure);
            app.Coche2Panel.Title = 'Coche 2';
            app.VelocidadkmhEditFieldLabel = uilabel(app.Coche2Panel);
            app.VelocidadkmhEditFieldLabel.Text = 'Velocidad (km/h)';
            app.velocidad2 = uieditfield(app.Coche2Panel, 'numeric');
            app.AlturadelaAntenamEditFieldLabel = uilabel(app.Coche2Panel);
            app.AlturadelaAntenamEditFieldLabel.Text = 'Altura de la Antena(m)';
            app.altura2 = uieditfield(app.Coche2Panel, 'numeric');
            app.TipodeAntenaDropDown_2Label = uilabel(app.Coche2Panel);
            app.TipodeAntenaDropDown_2Label.Text = 'Tipo de Antena';
            app.antena2 = uidropdown(app.Coche2Panel);
            app.antena2.Items = {'omni', 'dipole', 'half-wave-dipole', 'patch', 'xpol', 'rhcp-dipole', 'lhcp-dipole', 'lhcp-rhcp-dipole', 'ula2', 'ula4', 'ula8'};
            app.antena2.Value = 'omni';
            app.DistanciaentrecochesmLabel = uilabel(app.UIFigure);
            app.DistanciaentrecochesmLabel.Text = 'Distancia entre coches(m)';
            app.distancia = uieditfield(app.UIFigure, 'numeric');
            app.EjecutarQUADRIGAButton = uibutton(app.UIFigure, 'push');
            app.EjecutarQUADRIGAButton.ButtonPushedFcn = createCallbackFcn(app, @EjecutarQUADRIGAButtonPushed, true);
            app.EjecutarQUADRIGAButton.Text = 'Ejecutar QUADRIGA';
            app.APPPARAEJECUCINDEQUADRIGALabel = uilabel(app.UIFigure);
            app.APPPARAEJECUCINDEQUADRIGALabel.Text = 'APP PARA EJECUCIÓN DE QUADRIGA';
            app.DistanciaentrecarrilesmLabel = uilabel(app.UIFigure);
            app.DistanciaentrecarrilesmLabel.Text = 'Distancia entre carriles(m)';
            app.distancia_carriles = uieditfield(app.UIFigure, 'numeric');
            app.FrecuenciaDopplerMximaEditFieldLabel = uilabel(app.UIFigure);
            app.FrecuenciaDopplerMximaEditFieldLabel.Text = 'Frecuencia Doppler Máxima';
            app.fDop = uieditfield(app.UIFigure, 'numeric');
            app.fDop.Editable = 'off';
            app.DirecciondelCoche2DropDownLabel = uilabel(app.UIFigure);
            app.DirecciondelCoche2DropDownLabel.Text = 'Direccion del Coche 2';
            app.direccion = uidropdown(app.UIFigure);
            app.direccion.Items = {'Misma Direccion', 'Direccion Opuesta'};
            app.direccion.Value = 'Misma Direccion';
            app.Layout = uibutton(app.UIFigure, 'push');
            app.Layout.ButtonPushedFcn = createCallbackFcn(app, @LayoutButtonPushed, true);
            app.Layout.Text = 'Mostrar Layout';
            app.ENVIOATRAVESDELTRAYECTOLabel = uilabel(app.UIFigure);
            app.ENVIOATRAVESDELTRAYECTOLabel.Text = 'ENVIO A TRAVES DEL TRAYECTO';
            app.NmerodeBloquesdeRecursoEditFieldLabel = uilabel(app.UIFigure);
            app.NmerodeBloquesdeRecursoEditFieldLabel.Text = 'Número de Bloques de Recurso';
            app.numRB = uieditfield(app.UIFigure, 'numeric');
            app.TamaodelosBloquesdeRecursoEditFieldLabel = uilabel(app.UIFigure);
            app.TamaodelosBloquesdeRecursoEditFieldLabel.Text = 'Tamaño de los Bloques de Recurso';
            app.rbSize = uieditfield(app.UIFigure, 'numeric');
            app.TamaodelPrefijoCclicoEditFieldLabel = uilabel(app.UIFigure);
            app.TamaodelPrefijoCclicoEditFieldLabel.Text = 'Tamaño del Prefijo Cíclico';
            app.cpLen = uieditfield(app.UIFigure, 'numeric');
            app.PeriododeTramaEditFieldLabel = uilabel(app.UIFigure);
            app.PeriododeTramaEditFieldLabel.Text = 'Periodo de Trama';
            app.frameDur = uieditfield(app.UIFigure, 'numeric');
            app.OFDM = uibutton(app.UIFigure, 'push');
            app.OFDM.ButtonPushedFcn = createCallbackFcn(app, @OFDMButtonPushed, true);
            app.OFDM.Text = 'Enviar OFDM';
            app.FOFDM = uibutton(app.UIFigure, 'push');
            app.FOFDM.ButtonPushedFcn = createCallbackFcn(app, @FOFDMButtonPushed, true);
            app.FOFDM.Text = 'Enviar F-OFDM';
            app.UFMC = uibutton(app.UIFigure, 'push');
            app.UFMC.ButtonPushedFcn = createCallbackFcn(app, @UFMCButtonPushed, true);
            app.UFMC.Text = 'Enviar UFMC';
            app.BERmedioEditFieldLabel = uilabel(app.UIFigure);
            app.BERmedioEditFieldLabel.Text = 'BER medio';
            app.BERmedioOFDM = uieditfield(app.UIFigure, 'numeric');
            app.BERmedioOFDM.Editable = 'off';
            app.THRmedioEditFieldLabel = uilabel(app.UIFigure);
            app.THRmedioEditFieldLabel.Text = 'THR medio';
            app.THRmedioOFDM = uieditfield(app.UIFigure, 'numeric');
            app.THRmedioOFDM.Editable = 'off';
            app.BERmedioEditFieldLabel_2 = uilabel(app.UIFigure);
            app.BERmedioEditFieldLabel_2.Text = 'BER medio';
            app.BERmedioFOFDM = uieditfield(app.UIFigure, 'numeric');
            app.BERmedioFOFDM.Editable = 'off';
            app.THRmedioEditFieldLabel_2 = uilabel(app.UIFigure);
            app.THRmedioEditFieldLabel_2.Text = 'THR medio';
            app.THRmedioFOFDM = uieditfield(app.UIFigure, 'numeric');
            app.THRmedioFOFDM.Editable = 'off';
            app.THRmedioUFMC = uieditfield(app.UIFigure, 'numeric');
            app.THRmedioUFMC.Editable = 'off';
            app.BERmedioEditFieldLabel_3 = uilabel(app.UIFigure);
            app.BERmedioEditFieldLabel_3.Text = 'BER medio';
            app.BERmedioUFMC = uieditfield(app.UIFigure, 'numeric');
            app.BERmedioUFMC.Editable = 'off';
            app.THRmedioEditFieldLabel_3 = uilabel(app.UIFigure);
            app.THRmedioEditFieldLabel_3.Text = 'THR medio';
            app.TiempodesimulacionEditFieldLabel = uilabel(app.UIFigure);
            app.TiempodesimulacionEditFieldLabel.Text = 'Tiempo de simulacion';
            app.tiempo = uieditfield(app.UIFigure, 'numeric');
            app.Retrasos = uiaxes(app.UIFigure);
            title(app.Retrasos, 'Retraso medio')
            xlabel(app.Retrasos, 'X')
            ylabel(app.Retrasos, 'Y')
            zlabel(app.Retrasos, 'Z')
            app.ganancias = uiaxes(app.UIFigure);
            title(app.ganancias, 'Ganancia media')
            xlabel(app.ganancias, 'X')
            ylabel(app.ganancias, 'Y')
            zlabel(app.ganancias, 'Z')
            app.doppler = uiaxes(app.UIFigure);
            title(app.doppler, 'Espectro Doppler')
            xlabel(app.doppler, 'X')
            ylabel(app.doppler, 'Y')
            zlabel(app.doppler, 'Z')
            app.BERPlot = uiaxes(app.UIFigure);
            title(app.BERPlot, 'BER')
            xlabel(app.BERPlot, 'X')
            ylabel(app.BERPlot, 'Y')
            zlabel(app.BERPlot, 'Z')
            app.THRPlot = uiaxes(app.UIFigure);
            title(app.THRPlot, 'THROUGHPUT')
            xlabel(app.THRPlot, 'X')
            ylabel(app.THRPlot, 'Y')
            zlabel(app.THRPlot, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = APPV2V

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end